classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GeneratePatternsPanel          matlab.ui.container.Panel
        NumberofRowsEditFieldLabel     matlab.ui.control.Label
        NumberofRowsEditField          matlab.ui.control.NumericEditField
        NumberofColumsEditFieldLabel   matlab.ui.control.Label
        NumberofColumsEditField        matlab.ui.control.NumericEditField
        GenerateButton                 matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        CameraCalibrationPanel         matlab.ui.container.Panel
        openfilesButton                matlab.ui.control.Button
        CalibrationStartsButton        matlab.ui.control.Button
        InEditFieldLabel               matlab.ui.control.Label
        InEditField                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        EditField_9                    matlab.ui.control.NumericEditField
        k1EditFieldLabel               matlab.ui.control.Label
        k1EditField                    matlab.ui.control.NumericEditField
        k2EditFieldLabel               matlab.ui.control.Label
        k2EditField                    matlab.ui.control.NumericEditField
        ExportDataButton               matlab.ui.control.Button
        TPatternPitchumEditFieldLabel  matlab.ui.control.Label
        TPatternPitchumEditField       matlab.ui.control.NumericEditField
        tPixelSizeumEditFieldLabel     matlab.ui.control.Label
        tPixelSizeumEditField          matlab.ui.control.NumericEditField
        fFocalLengthumEditFieldLabel   matlab.ui.control.Label
        fFocalLengthumEditField        matlab.ui.control.NumericEditField
        TextArea_2                     matlab.ui.control.TextArea
        TextArea_3                     matlab.ui.control.TextArea
        ReprojectionErrorPanel         matlab.ui.container.Panel
        MeanReprojectionErrorTextAreaLabel  matlab.ui.control.Label
        MeanReprojectionErrorTextArea  matlab.ui.control.TextArea
        CalculateReprojectionErrorButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Path 
        filenames
       
    end
    
    properties (Access = public)
        CameraData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: openfilesButton
        function openfilesButtonPushed(app, event)
            [app.filenames, app.Path] = uigetfile({'*.bmp;*.png;*.jpg'},'MultiSelect','on');
            app.TextArea_2.Value = 'Input images completed! Please set parameters.';
            if isempty(app.filenames)
                app.TextArea_2.Value = 'Error: Please Select the right images';  
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            M = app.NumberofRowsEditField.Value;
            N = app.NumberofColumsEditField.Value;
            img = generate_pattern(M, N);
            if all(all(img == 0))
                app.TextArea.Value = 'Error: Numbers should be odd!';
            else
                app.TextArea.Value = 'Generation Success!';
            end
        end

        % Callback function: CalibrationStartsButton, 
        % CameraCalibrationPanel
        function CalibrationStartsButtonPushed(app, event)
            num = length(app.filenames);
            for i = 1:num
                fname = fullfile(app.Path, app.filenames{i});
                imgs(:,:,i) = imread(fname);
            end            
            T = app.TPatternPitchumEditField.Value;
            t = app.tPixelSizeumEditField.Value;
            f = app.fFocalLengthumEditField.Value;
            app.TextArea_3.Value = "processing ... Please wait";
            app.CameraData = main_calibration_app(imgs, 0, [], T, t, f);
            app.InEditField.Value = app.CameraData.In(1,1);
            app.EditField_2.Value = app.CameraData.In(2,1);
            app.EditField_3.Value = app.CameraData.In(3,1);
            app.EditField_4.Value = app.CameraData.In(1,2);
            app.EditField_5.Value = app.CameraData.In(2,2);
            app.EditField_6.Value = app.CameraData.In(3,2);
            app.EditField_7.Value = app.CameraData.In(1,3);
            app.EditField_8.Value = app.CameraData.In(2,3);
            app.EditField_9.Value = app.CameraData.In(3,3);
            app.k1EditField.Value = app.CameraData.k(1);
            app.k2EditField.Value = app.CameraData.k(2);
            app.TextArea_3.Value = "Calibration Success!";
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            export2wsdlg({'export camera parameters'}, {'CameraData'}, {app.CameraData});
        end

        % Button pushed function: CalculateReprojectionErrorButton
        function CalculateReprojectionErrorButtonPushed(app, event)
            Err = main_re_app(app.CameraData);
            app.MeanReprojectionErrorTextArea.Value = "Mean Reprojection Error: " + num2str(Err);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GeneratePatternsPanel = uipanel(app.UIFigure);
            app.GeneratePatternsPanel.Title = 'Generate Patterns';
            app.NumberofRowsEditFieldLabel = uilabel(app.GeneratePatternsPanel);
            app.NumberofRowsEditFieldLabel.Text = 'Number of Rows';
            app.NumberofRowsEditField = uieditfield(app.GeneratePatternsPanel, 'numeric');
            app.NumberofColumsEditFieldLabel = uilabel(app.GeneratePatternsPanel);
            app.NumberofColumsEditFieldLabel.Text = 'Number of Colums';
            app.NumberofColumsEditField = uieditfield(app.GeneratePatternsPanel, 'numeric');
            app.GenerateButton = uibutton(app.GeneratePatternsPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.TextArea = uitextarea(app.GeneratePatternsPanel);
            app.TextArea.Value = {'Numbers should be odd !'};
            app.CameraCalibrationPanel = uipanel(app.UIFigure);
            app.CameraCalibrationPanel.Title = 'Camera Calibration';
            app.CameraCalibrationPanel.SizeChangedFcn = createCallbackFcn(app, @CalibrationStartsButtonPushed, true);
            app.openfilesButton = uibutton(app.CameraCalibrationPanel, 'push');
            app.openfilesButton.ButtonPushedFcn = createCallbackFcn(app, @openfilesButtonPushed, true);
            app.openfilesButton.Text = 'open files';
            app.CalibrationStartsButton = uibutton(app.CameraCalibrationPanel, 'push');
            app.CalibrationStartsButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationStartsButtonPushed, true);
            app.CalibrationStartsButton.Text = 'Calibration Starts !';
            app.InEditFieldLabel = uilabel(app.CameraCalibrationPanel);
            app.InEditFieldLabel.Text = 'In = [';
            app.InEditField = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.EditField_2 = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.EditField_3 = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.EditField_4 = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.EditField_5 = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.EditField_6 = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.EditField_7 = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.EditField_8 = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.Label = uilabel(app.CameraCalibrationPanel);
            app.Label.Text = ']';
            app.EditField_9 = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.k1EditFieldLabel = uilabel(app.CameraCalibrationPanel);
            app.k1EditFieldLabel.Text = 'k1 = ';
            app.k1EditField = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.k2EditFieldLabel = uilabel(app.CameraCalibrationPanel);
            app.k2EditFieldLabel.Text = 'k2 = ';
            app.k2EditField = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.ExportDataButton = uibutton(app.CameraCalibrationPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.TPatternPitchumEditFieldLabel = uilabel(app.CameraCalibrationPanel);
            app.TPatternPitchumEditFieldLabel.Text = 'T: Pattern Pitch (um)';
            app.TPatternPitchumEditField = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.tPixelSizeumEditFieldLabel = uilabel(app.CameraCalibrationPanel);
            app.tPixelSizeumEditFieldLabel.Text = 't: Pixel Size (um)';
            app.tPixelSizeumEditField = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.fFocalLengthumEditFieldLabel = uilabel(app.CameraCalibrationPanel);
            app.fFocalLengthumEditFieldLabel.Text = 'f: Focal Length (um)';
            app.fFocalLengthumEditField = uieditfield(app.CameraCalibrationPanel, 'numeric');
            app.TextArea_2 = uitextarea(app.CameraCalibrationPanel);
            app.TextArea_2.Value = {'Please select images and set hyperparameters'};
            app.TextArea_3 = uitextarea(app.CameraCalibrationPanel);
            app.TextArea_3.Value = {'Let''s calibrate the images!'};
            app.ReprojectionErrorPanel = uipanel(app.UIFigure);
            app.ReprojectionErrorPanel.Title = 'Reprojection Error';
            app.MeanReprojectionErrorTextAreaLabel = uilabel(app.ReprojectionErrorPanel);
            app.MeanReprojectionErrorTextAreaLabel.Text = 'Mean Reprojection Error';
            app.MeanReprojectionErrorTextArea = uitextarea(app.ReprojectionErrorPanel);
            app.CalculateReprojectionErrorButton = uibutton(app.ReprojectionErrorPanel, 'push');
            app.CalculateReprojectionErrorButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateReprojectionErrorButtonPushed, true);
            app.CalculateReprojectionErrorButton.Text = 'Calculate Reprojection Error';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end